classdef dialog_box_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        txtImageDirectory   matlab.ui.control.Label
        txtOutputDirectory  matlab.ui.control.Label
        Cy3Threshold        matlab.ui.control.EditField
        FITCthreshold       matlab.ui.control.EditField
        imageDirectory      matlab.ui.control.Button
        outputDirectory     matlab.ui.control.Button
        run                 matlab.ui.control.Button
        text5               matlab.ui.control.Label
        text6               matlab.ui.control.Label
        text7               matlab.ui.control.Label
        text8               matlab.ui.control.Label
        DropDownLabel       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        DropDown_3Label     matlab.ui.control.Label
        DropDown_3          matlab.ui.control.DropDown
        DropDown_4Label     matlab.ui.control.Label
        DropDown_4          matlab.ui.control.DropDown
        DropDown_5Label     matlab.ui.control.Label
        DropDown_5          matlab.ui.control.DropDown
        DropDown_6Label     matlab.ui.control.Label
        DropDown_6          matlab.ui.control.DropDown
        DropDown_7Label     matlab.ui.control.Label
        DropDown_7          matlab.ui.control.DropDown
        DropDown_8Label     matlab.ui.control.Label
        DropDown_8          matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dialog_box_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to dialog_box (see VARARGIN)
            
            % Choose default command line output for dialog_box
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            app.DropDownLabel.Visible = 'off';
            app.DropDown.Visible = 'off';
            app.DropDown_2Label.Visible = 'off';
            app.DropDown_2.Visible = 'off';
            app.DropDown_3Label.Visible = 'off';
            app.DropDown_3.Visible = 'off';
            app.DropDown_4Label.Visible = 'off';
            app.DropDown_4.Visible = 'off';
            app.DropDown_5Label.Visible = 'off';
            app.DropDown_5.Visible = 'off';
            app.DropDown_6Label.Visible = 'off';
            app.DropDown_6.Visible = 'off';
            app.DropDown_7Label.Visible = 'off';
            app.DropDown_7.Visible = 'off';
            app.DropDown_8Label.Visible = 'off';
            app.DropDown_8.Visible = 'off';
        end

        % Button pushed function: imageDirectory
        function imageDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to imageDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imgdir = uigetdir();
            set(handles.txtImageDirectory, 'string', imgdir);
            currentDir = pwd;
            cd(imgdir);
            images = extractfield(dir('**/*COMBO.tif'), 'name');
            for i = 1:8
                switch i
                    case 1
                        app.DropDownLabel.Text = images{i};
                        app.DropDownLabel.Visible = 'on';
                        app.DropDown.Visible = 'on';
                    case 2
                        app.DropDown_2Label.Text = images{i};
                        app.DropDown_2Label.Visible = 'on';
                        app.DropDown_2.Visible = 'on';
                    case 3
                        app.DropDown_3Label.Text = images{i};
                        app.DropDown_3Label.Visible = 'on';
                        app.DropDown_3.Visible = 'on';
                    case 4
                        app.DropDown_4Label.Text = images{i};
                        app.DropDown_4Label.Visible = 'on';
                        app.DropDown_4.Visible = 'on';
                    case 5
                        app.DropDown_5Label.Text = images{i};
                        app.DropDown_5Label.Visible = 'on';
                        app.DropDown_5.Visible = 'on';
                    case 6
                        app.DropDown_6Label.Text = images{i};
                        app.DropDown_6Label.Visible = 'on';
                        app.DropDown_6.Visible = 'on';
                    case 7
                        app.DropDown_7Label.Text = images{i};
                        app.DropDown_7Label.Visible = 'on';
                        app.DropDown_7.Visible = 'on';
                    case 8
                        app.DropDown_8Label.Text = images{i};
                        app.DropDown_8Label.Visible = 'on';
                        app.DropDown_8.Visible = 'on';
                end
            end
            cd(currentDir);
        end

        % Button pushed function: outputDirectory
        function outputDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to outputDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outdir = uigetdir();
            set(handles.txtOutputDirectory, 'string', outdir);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagefolder = get(handles.txtImageDirectory, 'string');
            outfolder = get(handles.txtOutputDirectory, 'string');
            Cy3threshold = str2double(get(handles.Cy3Threshold, 'string'));
            FITCthreshold = str2double(get(handles.FITCthreshold, 'string'));
            if strcmp(imagefolder, 'Select Image Directory') || strcmp(outfolder, 'Select Data Output Directory') || isnan(Cy3threshold) || isnan(FITCthreshold)
                errordlg('All fields are required');
            else
                
                layer_list = {
                    'Analyze All Layers', '0';
                    'Exclude Nerve Fiber Layer', '1';
                    'Exclude Ganglion Cell Layer', '2';
                    'Exclude Inner Plexiform Layer', '3';
                    'Exclude Inner Nuclear Layer', '4';
                    'Exclude Outer Plexiform Layer', '5';
                    'Exclude Outer Nuclear Layer', '6';
                    'Exclude All Layers', '7';
                    };
                exclusion_data = {
                    app.DropDownLabel.Text, layer_list{ismember(layer_list, app.DropDown.Value), 2};
                    app.DropDown_2Label.Text, layer_list{ismember(layer_list, app.DropDown_2.Value), 2};
                    app.DropDown_3Label.Text, layer_list{ismember(layer_list, app.DropDown_3.Value), 2};
                    app.DropDown_4Label.Text, layer_list{ismember(layer_list, app.DropDown_4.Value), 2};
                    app.DropDown_5Label.Text, layer_list{ismember(layer_list, app.DropDown_5.Value), 2};
                    app.DropDown_6Label.Text, layer_list{ismember(layer_list, app.DropDown_6.Value), 2};
                    app.DropDown_7Label.Text, layer_list{ismember(layer_list, app.DropDown_7.Value), 2};
                    app.DropDown_8Label.Text, layer_list{ismember(layer_list, app.DropDown_8.Value), 2};
                    };
                cross_section_staining_analysis(imagefolder, Cy3threshold, FITCthreshold, outfolder, exclusion_data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Retinal Image Analyzer';
            app.figure1.Tag = 'figure1';
            app.txtImageDirectory = uilabel(app.figure1);
            app.txtImageDirectory.Tag = 'txtImageDirectory';
            app.txtImageDirectory.Text = 'Select Image Directory';
            app.txtOutputDirectory = uilabel(app.figure1);
            app.txtOutputDirectory.Tag = 'txtOutputDirectory';
            app.txtOutputDirectory.Text = 'Select Data Output Directory';
            app.Cy3Threshold = uieditfield(app.figure1, 'text');
            app.Cy3Threshold.Tag = 'Cy3Threshold';
            app.FITCthreshold = uieditfield(app.figure1, 'text');
            app.FITCthreshold.Tag = 'FITCthreshold';
            app.imageDirectory = uibutton(app.figure1, 'push');
            app.imageDirectory.ButtonPushedFcn = createCallbackFcn(app, @imageDirectory_Callback, true);
            app.imageDirectory.Tag = 'imageDirectory';
            app.imageDirectory.Text = 'Browse';
            app.outputDirectory = uibutton(app.figure1, 'push');
            app.outputDirectory.ButtonPushedFcn = createCallbackFcn(app, @outputDirectory_Callback, true);
            app.outputDirectory.Tag = 'outputDirectory';
            app.outputDirectory.Text = 'Browse';
            app.run = uibutton(app.figure1, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Cy3 Threshold';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'FITC Threshold';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Data Output Directory';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Image Directory';
            app.DropDownLabel = uilabel(app.figure1);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.figure1);
            app.DropDown.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown.Value = 'Analyze All Layers';
            app.DropDown_2Label = uilabel(app.figure1);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.figure1);
            app.DropDown_2.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_2.Value = 'Analyze All Layers';
            app.DropDown_3Label = uilabel(app.figure1);
            app.DropDown_3Label.Text = 'Drop Down';
            app.DropDown_3 = uidropdown(app.figure1);
            app.DropDown_3.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_3.Value = 'Analyze All Layers';
            app.DropDown_4Label = uilabel(app.figure1);
            app.DropDown_4Label.Text = 'Drop Down';
            app.DropDown_4 = uidropdown(app.figure1);
            app.DropDown_4.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_4.Value = 'Analyze All Layers';
            app.DropDown_5Label = uilabel(app.figure1);
            app.DropDown_5Label.Text = 'Drop Down';
            app.DropDown_5 = uidropdown(app.figure1);
            app.DropDown_5.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_5.Value = 'Analyze All Layers';
            app.DropDown_6Label = uilabel(app.figure1);
            app.DropDown_6Label.Text = 'Drop Down';
            app.DropDown_6 = uidropdown(app.figure1);
            app.DropDown_6.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_6.Value = 'Analyze All Layers';
            app.DropDown_7Label = uilabel(app.figure1);
            app.DropDown_7Label.Text = 'Drop Down';
            app.DropDown_7 = uidropdown(app.figure1);
            app.DropDown_7.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_7.Value = 'Analyze All Layers';
            app.DropDown_8Label = uilabel(app.figure1);
            app.DropDown_8Label.Text = 'Drop Down';
            app.DropDown_8 = uidropdown(app.figure1);
            app.DropDown_8.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer', 'Exclude All Layers', ''};
            app.DropDown_8.Value = 'Analyze All Layers';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_box_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)dialog_box_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end